classdef BeltApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MenuProject                matlab.ui.container.Menu
        MenuProjectNew             matlab.ui.container.Menu
        MenuProjectOpen            matlab.ui.container.Menu
        MenuProjectRecent          matlab.ui.container.Menu
        MenuProjectClose           matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        GridLayoutTopLeft          matlab.ui.container.GridLayout
        projectnameLabel           matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        Tab1                       matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        RightPanel                 matlab.ui.container.Panel
        MainPanel                  matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        MainTab                    matlab.ui.container.TabGroup
        MainTabHome                matlab.ui.container.Tab
        HomeHTML                   matlab.ui.control.HTML
        MainTabProject             matlab.ui.container.Tab
        ProjectHTML                matlab.ui.control.HTML
        MainTabArtifactCorrection  matlab.ui.container.Tab
        BeltArtifactCorrectionComponent  BeltArtifactCorrectionComponent
        MainTabDeconvolution       matlab.ui.container.Tab
        MainTabGraph               matlab.ui.container.Tab
        MainTabExport              matlab.ui.container.Tab
        LogPanel                   matlab.ui.container.Panel
        LogTextArea                matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        project; 
    end

    properties (Access = private)
        artifactcorrectionobj = [];
    end

    methods (Access = public)
        
        %% CALLBACKS
        function OpenProject(app, filename)

            % load the project data
            load(filename, "-mat");

            % store the project in the app variable
            app.project = project;

            % Show the project name in the title bar
            app.UIFigure.Name = ['Breda Experience Lab Toolbox     -== ', app.project.project_name, ' project ==-'];
            app.projectnameLabel.Text =  app.project.project_name;

            % populate the project tabel with the participant data
            app.populate_participant_table();

            % add this project to the 'recent' list in the
            % prefences file
            app.add_recent();

            % Pass the project details to the project html page
             app.ProjectHTML.Data = struct(...
                "ProjectName", ['Project: ', app.project.project_name],...
			    "ProjectDir", app.project.project_directory,...
			    "RawDir", app.project.raw_data_directory,...
			    "ProcessedDir", app.project.processed_data_directory,...
			    "OutputDir", app.project.output_directory...
            );

            
        end

        function OpenRecentProject(app, recent_index)
            % open the recent project according to the selected index 
            pref = ['recent', num2str(recent_index)];

            if ispref('belt', pref)
                % get the project name, dir, and date from the preferences
                recent = getpref('belt', pref);

                % read the project data
                % recent(2) is the project directory
                % recent(1) is the project name
                full_file = fullfile(recent(2), append('project_', string(recent(1)), '.belt'));

                % load the project and show the project data
                app.OpenProject(full_file);

                % write a message in the log
                msg = ['recent project ''', app.project.project_name, ''' opened'];
                app.AppendLog(msg);

            end
        end

        % the index of the recent menu item is stored as event.Source.Position
       function OpenRecentProjectFromMenu(app, event)
            app.OpenRecentProject(event.Source.Position);
        end

         % the index of the recent menu item is stored as event.HTMLEventData
        function OpenRecentProjectFromHTML(app, event)
            app.OpenRecentProject(event.HTMLEventData);
        end

        % save the project struct
        function SaveProject(app)
            % save the project struct as a matlab datafile
            path_filename = fullfile(app.project.project_directory, ['project_' app.project.project_name '.belt']);
            project = app.project;
            save(path_filename, 'project');

        end

        % do artifact correction
        function ArtifactCorrection(app, cfg)
            % check input
            if ~isfield(app.project, 'project_name')
                error('The project struct has no project_name field. I cannot create a project bookkeeping file without it.');
            end
            if ~isfield(app.project, 'project_directory')
                error('The project struct has no project_directory field. I cannot create a project bookkeeping file without it.');
            end
            if ~isfield(app.project, 'raw_data_directory')
                error('The project struct has no raw_data_directory field. Run create_new_project() first.');
            end
            if ~isfield(app.project, 'processed_data_directory')
                error('The project struct has no processed_data_directory field. Run create_new_project() first.');
            end
            if ~isfield(cfg, 'segment_nr')
                error('Provide a cfg.segment_nr');
            end
            if ~isfield(cfg, 'pp_nr')
                error('Provide a cfg.pp_nr');
            end
            if ~isfield(cfg, 'handle_already_cleaned_segments')
                cfg.handle_already_cleaned_segments = 'skip';
            end

            % set the parameters for the artifact detection algorithm
            if ~isfield(cfg, 'timwin')
                cfg.timwin = 20;
            end
            if ~isfield(cfg, 'threshold')
                cfg.threshold = 5;
            end
            if ~isfield(cfg, 'default_solution')
                cfg.default_solution = 'linear';
            end

            % for readability of the code
            pp_nr = cfg.pp_nr;
            pp_label = app.project.pp_labels{pp_nr};
            segment_nr = cfg.segment_nr;
            segment_name = app.project.segment(segment_nr).name;

            if ~isfield(cfg, 'segment_identifier')
                cfg.segment_identifier = ['Segment: ', segment_name, ', participant: ', pp_label];
            end

            % check whether artifact correction is even possible and needed
            [answer, msg] = artifact_correction_is_possible(cfg, app.project);
            app.AppendLog(msg);
            if ~answer % artifact correction is not possible or not needed
                return; % skip the rest of the function
            end

            % load the segmented raw data
            path_filename = fullfile(app.project.processed_data_directory, ['segment_raw_' segment_name  '_' pp_label '.mat']);
            load(path_filename, 'segment_raw'); % loads segment_raw

            % get potential artifacts
            cfg.artifacts = get_potential_artifacts(cfg, segment_raw);
            
            % open the artifact correction window to get the artifact corrected data
            % corrected_data = open_artifact_correction_window(cfg, segment_raw); % opens the app in a seperate window 
            
            % show the raw data and potential artifacts in the the artifact correction component
            cfg.time = segment_raw.time;
            app.BeltArtifactCorrectionComponent.showData(cfg, segment_raw.conductance_raw);

            % process the artifact corrected data when the [Save] button is pressed
            % corrected_data = 

            % === TODO :: SAVE THE CORRECTED DATA WHEN THE SAVE BUTTON IS PRESSED ===
            % check whether cleaned data has been returned
            % if ~isempty(corrected_data)
            %     % save the artifact corrected segment
            %     segment_raw.conductance_artifact_corrected = corrected_data;
            %     [app.project, msg] = save_artifact_corrected_data(cfg, app.project, segment_raw);
            %     % update the table
            %     app.populate_participant_table();
            %     % provide feedback
            %     app.AppendLog(msg);
            % end

        end

        % toggle include variable (true|false)
        function ToggleInclude(app, cfg)
            pp_nr = cfg.pp_nr;
            pp_label = app.project.pp_labels{pp_nr};
            segment_nr = cfg.segment_nr;
            segment_name = app.project.segment(segment_nr).name;

            if app.project.segment(segment_nr).include(pp_nr) % if true
                include_status = 'false'; % set to false
                app.project.segment(segment_nr).include(pp_nr) = 0;
            else % if false
                include_status = 'true'; % set to true
                app.project.segment(segment_nr).include(pp_nr) = 1;
            end       
            % save the project
            app.SaveProject();
            % update the table
            app.populate_participant_table();
            % provide feedback
            msg = sprintf('Segment name  = ''%s'', participant ''%s'': include set to ''%s''.', segment_name, pp_label, include_status);
            app.AppendLog(msg);
        end

        %% VISUALS

        %% UI ELEMENTS


        %% CALCULATIONS


    end
    
    methods (Access = private)
        
        
        % Add event to the log
        function AppendLog(app, msg)
            if ~isempty(msg)
                t = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss');
                t = string(t);
                new_text = sprintf('[%s] %s\n', t, msg);
                app.LogTextArea.Value = [app.LogTextArea.Value(:)', {new_text}];
                scroll(app.LogTextArea, "bottom");
            end
        end


        % populate the participant data table
        function populate_participant_table(app)
            % update the tabs
            app.update_tabs();

            % create a table from the project data
            for segment_i = 1:app.project.nof_segments
                pp = app.project.pp_labels;
                seg = logical(app.project.segment(segment_i).segmented);
                art = logical(app.project.segment(segment_i).artifact_corrected);
                dec = logical(app.project.segment(segment_i).deconvolved);
                inc = logical(app.project.segment(segment_i).include);
                t = table(pp, seg, art, dec, inc);

                % fill the table with the contents of table t
                app.UITable(segment_i).Data = t;        
            end

        end

        % update the tabs
        function update_tabs(app)
            % note the number of tabs
            nof_tabs = size(app.TabGroup.Children, 1);
            % for each segment, create a tab
            for segment_i = 1:app.project.nof_segments
                if segment_i > nof_tabs
                    % this tab does not exist yet, so create it.
                    tabgroup = app.TabGroup;
                    t = uitab(tabgroup, 'Title', app.project.segment(segment_i).name);
                    % copy the table of tab 1 to add it to this new tab.
                    % using matlab function copyobj(h, p); h = obj to copy, p = parent of the new object
                    app.UITable(segment_i) = copyobj(app.UITable(1), app.TabGroup.Children(segment_i));
                    % add callback function
                    app.UITable(segment_i).CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);

                else
                    % this tab does exist, so rename it
                    app.TabGroup.Children(segment_i).Title = app.project.segment(segment_i).name;
                end
            end
        end

        % append the current project to the list of recent projects
        function add_recent(app)

            % add the current project as the most recent
            t = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss');
            t = string(t);
            setpref('belt', 'recent1', {app.project.project_name, app.project.project_directory, t});


            % get the current recent list, and store in the next recent spots
            offset = 1; % move all previous items 1 spot down
            for recent_i = 1:5
                old_pref = ['recent', num2str(recent_i)];
                new_pref = ['recent', num2str(recent_i + offset)];

                if ispref('belt', old_pref) % if a recent project exists
                    recent_temp = getpref('belt', old_pref); % get the project name, dir, and date
                    % then check whether it is not the current project
                    if strcmp(recent_temp(1), app.project.project_name) && strcmp(recent_temp(2), app.project.project_directory)
                        % same project as current one, so skip this to avoid duplicates
                        % if we skipped one, the remaining recent projects
                        % can keep their place
                        offset = max(offset-1, 0);
                    else
                        % save the previous recent projects
                        setpref('belt', new_pref, recent_temp);
                    end
                end
            end

            % update the recent menu
           app.show_recent_projects();

            % remove preferences of belt app === for testing ===
            % rmpref('belt', 'recent1');
            % rmpref('belt', 'recent2');
            % rmpref('belt', 'recent3');
            % rmpref('belt', 'recent4');
            % rmpref('belt', 'recent5');
            % rmpref('belt');
        end

        % show the recent projects in the menu and on the home tab
        function show_recent_projects(app)
            
            % First remove the previous recent projects list
            delete(app.MenuProjectRecent.Children);
            html_recent = {'recent1','recent2','recent3','recent4','recent5'};
            
            % the add them again, based on the recent projects list 
            % that is stored in the preferences
            for recent_i = 1:5
                pref = ['recent', num2str(recent_i)];

                if ispref('belt', pref) % if a recent project exists

                    % get the project name, dir, and date
                    recent = getpref('belt', pref); 
                    % add it to the recent menu
                    mitem = uimenu(app.MenuProjectRecent);
                    mitem.MenuSelectedFcn = createCallbackFcn(app, @OpenRecentProjectFromMenu, true);
                    mitem.Text = string(recent(1));

                    % add it to the home tab
                    % === TODO ===
                    html_recent(recent_i) = recent(1);
                end
            end

            app.HomeHTML.Data = html_recent;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, cfg)
          
            % set the title bar
            app.UIFigure.Name = 'Breda Experience Lab Toolbox';

            % show the recent projects in the menubar and on the home tab
            app.show_recent_projects();

            %Make app fullscreen
            drawnow;
            % app.UIFigure.WindowState = 'maximized';

            % === FOR TESTING :: load an existing project at startup to
            % populate the pp table
            % load('C:\projects\PSV\project_PSV.belt', "-mat");
            
            % add the project name to the title bar
            % app.UIFigure.Name = [app.UIFigure.Name, ' - ', project.project_name];


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Event received function: HomeHTML
        function HomeHTMLEventReceived(app, event)
            disp('HomeHTMLEventReceived');
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            if strcmp(eventName, 'OpenProject')
                app.OpenExistingProject(event);
            elseif strcmp(eventName, 'NewProject')
                app.OpenNewProject(event);
            elseif strcmp(eventName, 'RecentProject')
                app.OpenRecentProjectFromHTML(event);
            end
        end

        % Menu selected function: MenuProjectOpen
        function OpenExistingProject(app, event)

                % Let the user select a .belt project file
                [file,location] = uigetfile('*.belt', 'Open a BELT project', 'C:\');
                
                if file % a file was selected

                    % read the project data
                    full_file = fullfile(location, file);

                    % load the project and show the project data
                    app.OpenProject(full_file);

                    % write a message in the log
                    msg = ['existing project ''', app.project.project_name, ''' opened'];
                    app.AppendLog(msg);

                end
            
        end

        % Menu selected function: MenuProjectNew
        function OpenNewProject(app, event)
                app.UIFigure.Name = 'New project';
                app.AppendLog('opening a new project');
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            % Determine what was clicked on ...
            %   segment_name :: read form the title of the tab = event.Source.Parent.Title 
            %   pp_nr        :: is the row number = event.Indices(1)
            %   action       :: is the column number event.Indices(2) (1=pp label, 2=segment, 3=artifact correct, 4=deconvolve, 5=include)

            % for testing
            % msg = sprintf('Clicked on Segment ''%s'', Participant ''%s'', Column ''%s''.', event.Source.Parent.Title, app.project.pp_labels{indices(1)}, event.Source.ColumnName{indices(2)});
            % app.AppendLog(msg);

            % determine the segment_nr
            segment_name = event.Source.Parent.Title;
            segment_nr = 0;
            for segment_i = 1:app.project.nof_segments % check all segments
                if strcmp(app.project.segment(segment_i).name, segment_name) % compare segment names
                    segment_nr = segment_i; % segment found
                    break;
                end
            end
            % determine the participant nr
            pp_nr = indices(1); % = row nr

            % create the cfg struct
            cfg = [];
            cfg.pp_nr = pp_nr;
            cfg.segment_nr = segment_nr;

            switch indices(2) % column nr
                case 1 % pp label

                case 2 % segment

                case 3 % artifact correction
                    app.ArtifactCorrection(cfg);
                case 4 % deconvolution

                case 5 % include
                    % toggle the include value (true|false)
                    app.ToggleInclude(cfg);

            end    

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if strcmp(app.MainTab.SelectedTab.Title, 'Artifact Correction')
                cursorXY = app.UIFigure.CurrentPoint;
                % app.UIFigure.Name = ['(', num2str(cursorXY(1)), ', ', num2str(cursorXY(2)), ')'];
    
                % pass on the relative cursor position to the component
                % NOTE: WE MAY NOT NEED TO PASS ON THE COORDINATES,
                %       ONLY TO CALL THEMOUSEMOVE FUNCTION
                cursorX = cursorXY(1) - app.RightPanel.Position(1) - app.MainPanel.Position(1) - app.MainTab.Position(1) - app.MainTabArtifactCorrection.Position(1) - app.BeltArtifactCorrectionComponent.Position(1);
                cursorY = cursorXY(2) - app.RightPanel.Position(2) - app.MainPanel.Position(2) - app.MainTab.Position(2) - app.MainTabArtifactCorrection.Position(2) - app.BeltArtifactCorrectionComponent.Position(2);
                
                cursorXY(1) = cursorX;
                cursorXY(2) = cursorY;
                
                Pointertype = app.BeltArtifactCorrectionComponent.MouseMove(cursorXY);
                app.UIFigure.Pointer = Pointertype;
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if strcmp(app.MainTab.SelectedTab.Title, 'Artifact Correction')
                app.BeltArtifactCorrectionComponent.MouseUp();
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {595, 595};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BeltApp';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.MenuProject = uimenu(app.UIFigure);
            app.MenuProject.Text = 'Project';
            app.MenuProjectNew = uimenu(app.MenuProject);
            app.MenuProjectNew.MenuSelectedFcn = createCallbackFcn(app, @OpenNewProject, true);
            app.MenuProjectNew.Accelerator = 'N';
            app.MenuProjectNew.Text = 'New';
            app.MenuProjectOpen = uimenu(app.MenuProject);
            app.MenuProjectOpen.MenuSelectedFcn = createCallbackFcn(app, @OpenExistingProject, true);
            app.MenuProjectOpen.Accelerator = 'O';
            app.MenuProjectOpen.Text = 'Open';
            app.MenuProjectRecent = uimenu(app.MenuProject);
            app.MenuProjectRecent.Text = 'Recent';
            app.MenuProjectClose = uimenu(app.MenuProject);
            app.MenuProjectClose.Text = 'Close';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Segment';
            app.UITable = uitable(app.Tab1);
            app.UITable.ColumnName = {'Participant'; 'Cut'; 'Clean'; 'Split'; 'Use'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GridLayoutTopLeft = uigridlayout(app.GridLayout3);
            app.projectnameLabel = uilabel(app.GridLayoutTopLeft);
            app.projectnameLabel.Text = 'project name';
            app.RightPanel = uipanel(app.GridLayout);
            app.MainPanel = uipanel(app.RightPanel);
            app.MainPanel.Title = 'Main';
            app.GridLayout4 = uigridlayout(app.MainPanel);
            app.LogPanel = uipanel(app.GridLayout4);
            app.LogPanel.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogPanel);
            app.LogTextArea.Value = {'Starting log ...'};
            app.MainTab = uitabgroup(app.GridLayout4);
            app.MainTabHome = uitab(app.MainTab);
            app.MainTabHome.Title = 'Home';
            app.HomeHTML = uihtml(app.MainTabHome);
            app.HomeHTML.HTMLSource = 'BeltAppHome.html';
            app.HomeHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @HomeHTMLEventReceived, true);
            app.MainTabProject = uitab(app.MainTab);
            app.MainTabProject.Title = 'Project';
            app.ProjectHTML = uihtml(app.MainTabProject);
            app.ProjectHTML.HTMLSource = 'BeltAppProject.html';
            app.MainTabArtifactCorrection = uitab(app.MainTab);
            app.MainTabArtifactCorrection.Title = 'Artifact Correction';
            app.BeltArtifactCorrectionComponent = BeltArtifactCorrectionComponent(app.MainTabArtifactCorrection);
            app.BeltArtifactCorrectionComponent.ArtifactDragging = [];
            app.MainTabDeconvolution = uitab(app.MainTab);
            app.MainTabDeconvolution.Title = 'Deconvolution';
            app.MainTabGraph = uitab(app.MainTab);
            app.MainTabGraph.Title = 'Graph';
            app.MainTabExport = uitab(app.MainTab);
            app.MainTabExport.Title = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeltApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end